classdef SI_data_logger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ProjectTab                      matlab.ui.container.Tab
        UpdateButton                    matlab.ui.control.Button
        CreateButton_2                  matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        ProjectNameTextArea             matlab.ui.control.TextArea
        ProjectNameTextAreaLabel        matlab.ui.control.Label
        ProtocolTextArea                matlab.ui.control.TextArea
        ProtocolTextAreaLabel           matlab.ui.control.Label
        ExperimenterTextArea            matlab.ui.control.TextArea
        ExperimenterTextAreaLabel       matlab.ui.control.Label
        InstitutionTextArea             matlab.ui.control.TextArea
        InstitutionTextAreaLabel        matlab.ui.control.Label
        LabTextArea                     matlab.ui.control.TextArea
        LabTextAreaLabel                matlab.ui.control.Label
        SelectButton                    matlab.ui.control.Button
        Project_homedirectoryEditField  matlab.ui.control.EditField
        ProjecthomedirectoryEditFieldLabel  matlab.ui.control.Label
        Project_description             matlab.ui.control.TextArea
        DescriptionTextArea_2Label      matlab.ui.control.Label
        SubjectTab                      matlab.ui.container.Tab
        StrainDropDown                  matlab.ui.control.DropDown
        StrainDropDownLabel             matlab.ui.control.Label
        AddSubjectButton                matlab.ui.control.Button
        SurgerydateDatePicker           matlab.ui.control.DatePicker
        SurgerydateDatePickerLabel      matlab.ui.control.Label
        DOBDatePicker_2                 matlab.ui.control.DatePicker
        DOBDatePicker_2Label            matlab.ui.control.Label
        HemisphereDropDown              matlab.ui.control.DropDown
        HemisphereDropDownLabel         matlab.ui.control.Label
        VirusDropDown                   matlab.ui.control.DropDown
        VirusDropDownLabel              matlab.ui.control.Label
        SurgeryDropDown                 matlab.ui.control.DropDown
        SurgeryDropDownLabel            matlab.ui.control.Label
        subject_description             matlab.ui.control.TextArea
        DescrptionLabel                 matlab.ui.control.Label
        WeightgrSpinner                 matlab.ui.control.Spinner
        WeightgrSpinnerLabel            matlab.ui.control.Label
        InjectiondateDatePicker         matlab.ui.control.DatePicker
        InjectiondateDatePickerLabel    matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        GenotypeDropDown                matlab.ui.control.DropDown
        GenotypeDropDownLabel           matlab.ui.control.Label
        SpeciesDropDown                 matlab.ui.control.DropDown
        SpeciesDropDownLabel            matlab.ui.control.Label
        AnimalIDTextArea                matlab.ui.control.TextArea
        AnimalIDTextAreaLabel           matlab.ui.control.Label
        SessionTab                      matlab.ui.container.Tab
        CheckBox_Stim_dir               matlab.ui.control.CheckBox
        CheckBox_Stim                   matlab.ui.control.CheckBox
        StimDropDown                    matlab.ui.control.DropDown
        StimDropDownLabel               matlab.ui.control.Label
        PreviewButton                   matlab.ui.control.Button
        CheckBox_Timepoint_dir          matlab.ui.control.CheckBox
        CheckBox_Pharmacology_dir       matlab.ui.control.CheckBox
        CheckBox_Labeling_dir           matlab.ui.control.CheckBox
        CheckBox_Anesthesia_dir         matlab.ui.control.CheckBox
        CheckBox_Condition_dir          matlab.ui.control.CheckBox
        CheckBox_FOV_dir                matlab.ui.control.CheckBox
        CheckBox_Pharmacology           matlab.ui.control.CheckBox
        CheckBox_Labeling               matlab.ui.control.CheckBox
        CheckBox_Anesthesia             matlab.ui.control.CheckBox
        CheckBox_Condition              matlab.ui.control.CheckBox
        CheckBox_Timepoint              matlab.ui.control.CheckBox
        CheckBox_FOV                    matlab.ui.control.CheckBox
        PharmacolDropDown               matlab.ui.control.DropDown
        PharmacolDropDownLabel          matlab.ui.control.Label
        LabelingDropDown                matlab.ui.control.DropDown
        LabelingDropDownLabel           matlab.ui.control.Label
        AnesthesiaDropDown              matlab.ui.control.DropDown
        AnesthesiaDropDownLabel         matlab.ui.control.Label
        WeightSpinner                   matlab.ui.control.Spinner
        WeightSpinnerLabel              matlab.ui.control.Label
        SessionsubjectDropDown          matlab.ui.control.DropDown
        SessionsubjectDropDownLabel     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        InitializeButton                matlab.ui.control.Button
        ConditionDropDown               matlab.ui.control.DropDown
        ConditionDropDownLabel          matlab.ui.control.Label
        TimePointSpinner                matlab.ui.control.Spinner
        TimePointSpinnerLabel           matlab.ui.control.Label
        SessionDescription              matlab.ui.control.TextArea
        DescriptionSectionTextAreaLabel  matlab.ui.control.Label
        FOVSpinner                      matlab.ui.control.Spinner
        FOVSpinnerLabel                 matlab.ui.control.Label
        SessionIDTextArea               matlab.ui.control.TextArea
        SessionIDTextAreaLabel          matlab.ui.control.Label
        AcquireTab                      matlab.ui.container.Tab
        AcquireanalogdataCheckBox       matlab.ui.control.CheckBox
        GrabButton                      matlab.ui.control.Button
        PostacquisitionPanel            matlab.ui.container.Panel
        UpdateButton_postacq            matlab.ui.control.Button
        AcquisitionIDDropDown           matlab.ui.control.DropDown
        AcquisitionIDDropDownLabel      matlab.ui.control.Label
        UseforanalysisDropDown          matlab.ui.control.DropDown
        UseforanalysisDropDownLabel     matlab.ui.control.Label
        CommentsTextArea                matlab.ui.control.TextArea
        CommentsTextAreaLabel           matlab.ui.control.Label
        ScannerDropDown                 matlab.ui.control.DropDown
        ScannerDropDownLabel            matlab.ui.control.Label
        DAQDropDown                     matlab.ui.control.DropDown
        DAQDropDownLabel                matlab.ui.control.Label
        ObjectiveDropDown               matlab.ui.control.DropDown
        ObjectiveDropDownLabel          matlab.ui.control.Label
        AcquisitionIDTextArea           matlab.ui.control.TextArea
        AcquisitionIDTextAreaLabel      matlab.ui.control.Label
        DescriptionAcqTextArea          matlab.ui.control.TextArea
        DescriptionAcqTextAreaLabel     matlab.ui.control.Label
        DatatypeDropDown                matlab.ui.control.DropDown
        DatatypeDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        PROJECT_ROOT_DIR
        PROJECT_ID
        SESSION_ID
        ACQ_ID = 0%this one is unique and must be initialize if not present.
        SUBJECT_ID
        BASE_FILE_NAME
        FOV
        CONDITION
        ANESTHESIA
        LABELING
        PHARMACOL
        STIM
        PATH_TO_SESSION %session root folder
        PATH_TO_SESSION_DATA_OPHYS %optic physiology data gets written here
        PATH_TO_SESSION_DATA_ANAT %anatomy data gets written here
        PATH_TO_SESSION_DERIVATIVES %pynapple needed variables will go here
        PATH_TO_DATA
        PATH_TO_DATA_REL
        TIMEPOINT
        DATA_TYPE
        APP_MODE = 'Offline'; %set to 'Offline' or 'SI-Online' (Offline if workign without SI for developing purposes)
        lamp_off_color = [1 0 0];
        lamp_on_color = [0 1 0];
    end
    
    methods (Access = public)
        
   %most are under DataLogger and named util_xxxxxx,  outside of the mapp file for better version
   %tracking.
        
        function init_session(app)
            %setup recording path based on session parameters - Make
            %compliant with [partial] BIDS and thinking of future NWB
            %📂 MyProject
            %└── 📂 sub-A2929
            %       └── 📂
            %       ses-A2929-200711-[cond_xx]-[T_xx]-[FOV_xx]-[anes_xxx]
            %%              └── 📂 ophys %optical physiology (Movies, arbitrary scans and analog data)
            %%              └── 📂 anat  %anatomical stuff (3D stacks, overviews etc)
            %%              └── 📂 derivates
            %%  project.yml or project.xml (supported)
            %%  sessions.csv
            %%  acquisition.csv
            %%  subjects.csv
            util_get_session_parameters(app);
            util_create_session_name(app,1); % 1 tells function to generate directory
            toggle_grab_status(app,'on');
            %util_log_session(app);
        end


        function toggle_grab_status(app,status)
            %enable or disable grab button
            switch status
                case 'on'
                    app.Lamp.Color = app.lamp_on_color;
                    app.GrabButton.Enable = 1;
                case 'off'
                    app.Lamp.Color = app.lamp_off_color;
                    app.GrabButton.Enable = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin("base","fprintf('\nStarting data logger')");
            app.AcquisitionIDTextArea.Value=sprintf("%05d",app.ACQ_ID);
            toggle_grab_status(app,'off');%make sure no grab can occur until data structure is set
        end

        % Button pushed function: GrabButton
        function GrabButtonPushed(app, event)
            util_grab(app);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            path_to_dir = uigetdir("D:\Data",'Select project root folder');
            app.Project_homedirectoryEditField.Value = path_to_dir;
            app.PROJECT_ROOT_DIR = path_to_dir;
            %check if root directory contains project file
            if isfile(fullfile(app.PROJECT_ROOT_DIR,'project.yml'))
                project_load(app)
            end
            %enable initialize button
            cd(path_to_dir);
            app.InitializeButton.Enable=1;
        end

        % Value changed function: SessionIDTextArea
        function SessionIDTextAreaValueChanged(app, event)
            toggle_grab_status(app,'off')
        end

        % Value changed function: AnimalIDTextArea
        function AnimalIDTextAreaValueChanged(app, event)
            toggle_grab_status(app,'off')
        end

        % Value changed function: FOVSpinner
        function FOVSpinnerValueChanged(app, event)
            toggle_grab_status(app,'off')
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            %generate session tree
            init_session(app)
        end

        % Button pushed function: LoadButton
        function project_load(app, event)
            status = util_project_load(app);
        end

        % Button pushed function: CreateButton_2
        function project_create(app, event)
            status = action_project_create(app);
        end

        % Button pushed function: AddSubjectButton
        function add_subject(app, event)
            util_add_subject(app);
        end

        % Value changed function: SessionsubjectDropDown
        function SessionsubjectDropDownValueChanged(app, event)
            
            toggle_grab_status(app,'off');
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            toggle_grab_status(app,'off');
        end

        % Value changed function: TimePointSpinner
        function TimePointSpinnerValueChanged(app, event)
            toggle_grab_status(app,'off');
            
        end

        % Value changed function: LabelingDropDown
        function LabelingDropDownValueChanged(app, event)
            toggle_grab_status(app,'off');
        end

        % Value changed function: PharmacolDropDown
        function PharmacolDropDownValueChanged(app, event)
            app.PHARMACOL = app.PharmacolDropDown.Value;
            toggle_grab_status(app,'off');
        end

        % Button pushed function: UpdateButton_postacq
        function UpdateButton_postacqPushed(app, event)
            util_post_acq_update(app);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            util_preview_session_filestructure(app);
        end

        % Value changed function: Project_description
        function Project_descriptionValueChanged(app, event)
            str = app.Project_description.Value;
            app.Project_description.Value = util_validate_description_text(str);
        end

        % Value changed function: subject_description
        function subject_descriptionValueChanged(app, event)
            str = app.subject_description.Value;
            app.subject_description.Value = util_validate_description_text(str);
        end

        % Value changed function: SessionDescription
        function SessionDescriptionValueChanged(app, event)
            str = app.SessionDescription.Value;
            app.SessionDescription.Value = util_validate_description_text(str);
        end

        % Value changed function: DescriptionAcqTextArea
        function DescriptionAcqTextAreaValueChanged(app, event)
            str = app.DescriptionAcqTextArea.Value;
            app.DescriptionAcqTextArea.Value = util_validate_description_text(str);
        end

        % Value changed function: CommentsTextArea
        function CommentsTextAreaValueChanged(app, event)
            str = app.CommentsTextArea.Value;
            app.CommentsTextArea.Value = util_validate_description_text(str);
        end

        % Value changed function: DatatypeDropDown
        function DatatypeDropDownValueChanged(app, event)
            util_update_acq_mode(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProjectTab = uitab(app.TabGroup);
            app.ProjectTab.Title = 'Project';
            app.DescriptionTextArea_2Label = uilabel(app.ProjectTab);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.Project_description = uitextarea(app.ProjectTab);
            app.Project_description.ValueChangedFcn = createCallbackFcn(app, @Project_descriptionValueChanged, true);
            app.Project_description.Tag = '__PROJECT_DESCRIPTION';
            app.Project_description.Tooltip = {'Describe your project  here'};
            app.ProjecthomedirectoryEditFieldLabel = uilabel(app.ProjectTab);
            app.ProjecthomedirectoryEditFieldLabel.Text = 'Project home directory';
            app.Project_homedirectoryEditField = uieditfield(app.ProjectTab, 'text');
            app.Project_homedirectoryEditField.Tooltip = {'All sessions and project db, metadate etc  will be stored under this folder.'};
            app.Project_homedirectoryEditField.Value = 'D:\Data';
            app.SelectButton = uibutton(app.ProjectTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.LabTextAreaLabel = uilabel(app.ProjectTab);
            app.LabTextAreaLabel.Text = 'Lab';
            app.LabTextArea = uitextarea(app.ProjectTab);
            app.LabTextArea.Tag = '__ANIMAL_ID';
            app.LabTextArea.Value = {'PBLab'};
            app.InstitutionTextAreaLabel = uilabel(app.ProjectTab);
            app.InstitutionTextAreaLabel.Text = 'Institution';
            app.InstitutionTextArea = uitextarea(app.ProjectTab);
            app.InstitutionTextArea.Tag = '__ANIMAL_ID';
            app.InstitutionTextArea.Value = {'Tel Aviv University'};
            app.ExperimenterTextAreaLabel = uilabel(app.ProjectTab);
            app.ExperimenterTextAreaLabel.Text = 'Experimenter';
            app.ExperimenterTextArea = uitextarea(app.ProjectTab);
            app.ExperimenterTextArea.Tag = '__ANIMAL_ID';
            app.ExperimenterTextArea.Tooltip = {'Enter your name here'};
            app.ExperimenterTextArea.Value = {''; ''};
            app.ProtocolTextAreaLabel = uilabel(app.ProjectTab);
            app.ProtocolTextAreaLabel.Text = 'Protocol #';
            app.ProtocolTextArea = uitextarea(app.ProjectTab);
            app.ProtocolTextArea.Tag = '__ANIMAL_ID';
            app.ProtocolTextArea.Tooltip = {'Enter ethics protocol number'};
            app.ProjectNameTextAreaLabel = uilabel(app.ProjectTab);
            app.ProjectNameTextAreaLabel.Text = 'Project Name';
            app.ProjectNameTextArea = uitextarea(app.ProjectTab);
            app.ProjectNameTextArea.Tag = '__ANIMAL_ID';
            app.ProjectNameTextArea.Tooltip = {'Project name'};
            app.LoadButton = uibutton(app.ProjectTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @project_load, true);
            app.LoadButton.Text = 'Load';
            app.CreateButton_2 = uibutton(app.ProjectTab, 'push');
            app.CreateButton_2.ButtonPushedFcn = createCallbackFcn(app, @project_create, true);
            app.CreateButton_2.Text = 'Create';
            app.UpdateButton = uibutton(app.ProjectTab, 'push');
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.SubjectTab = uitab(app.TabGroup);
            app.SubjectTab.Title = 'Subject';
            app.AnimalIDTextAreaLabel = uilabel(app.SubjectTab);
            app.AnimalIDTextAreaLabel.Text = 'Animal  ID';
            app.AnimalIDTextArea = uitextarea(app.SubjectTab);
            app.AnimalIDTextArea.ValueChangedFcn = createCallbackFcn(app, @AnimalIDTextAreaValueChanged, true);
            app.AnimalIDTextArea.Tag = '__ANIMAL_ID';
            app.SpeciesDropDownLabel = uilabel(app.SubjectTab);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.SubjectTab);
            app.SpeciesDropDown.Items = {'Mus musculus', 'Etruscan shrew', 'Zebra fish'};
            app.SpeciesDropDown.Value = 'Mus musculus';
            app.GenotypeDropDownLabel = uilabel(app.SubjectTab);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.SubjectTab);
            app.GenotypeDropDown.Items = {'WT', 'Thy1-GCaMP6s', ''};
            app.GenotypeDropDown.Value = 'WT';
            app.SexDropDownLabel = uilabel(app.SubjectTab);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.SubjectTab);
            app.SexDropDown.Items = {'?', 'M', 'F'};
            app.SexDropDown.Value = '?';
            app.InjectiondateDatePickerLabel = uilabel(app.SubjectTab);
            app.InjectiondateDatePickerLabel.Text = 'Injection  date';
            app.InjectiondateDatePicker = uidatepicker(app.SubjectTab);
            app.WeightgrSpinnerLabel = uilabel(app.SubjectTab);
            app.WeightgrSpinnerLabel.Text = 'Weight (gr)';
            app.WeightgrSpinner = uispinner(app.SubjectTab);
            app.WeightgrSpinner.Step = 0.1;
            app.DescrptionLabel = uilabel(app.SubjectTab);
            app.DescrptionLabel.Text = 'Descrption';
            app.subject_description = uitextarea(app.SubjectTab);
            app.subject_description.ValueChangedFcn = createCallbackFcn(app, @subject_descriptionValueChanged, true);
            app.subject_description.Tag = '__PROJECT_DESCRIPTION';
            app.SurgeryDropDownLabel = uilabel(app.SubjectTab);
            app.SurgeryDropDownLabel.Text = 'Surgery';
            app.SurgeryDropDown = uidropdown(app.SubjectTab);
            app.SurgeryDropDown.Items = {'3mm', '5mm', 'PoRTs', 'Crystal_Skull'};
            app.SurgeryDropDown.Value = '3mm';
            app.VirusDropDownLabel = uilabel(app.SubjectTab);
            app.VirusDropDownLabel.Text = 'Virus';
            app.VirusDropDown = uidropdown(app.SubjectTab);
            app.VirusDropDown.Items = {'None', 'php.eb-GCaMP8m', ''};
            app.VirusDropDown.Value = 'None';
            app.HemisphereDropDownLabel = uilabel(app.SubjectTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.SubjectTab);
            app.HemisphereDropDown.Items = {'L', 'R', 'Bi'};
            app.HemisphereDropDown.Value = 'L';
            app.DOBDatePicker_2Label = uilabel(app.SubjectTab);
            app.DOBDatePicker_2Label.Text = 'DOB';
            app.DOBDatePicker_2 = uidatepicker(app.SubjectTab);
            app.SurgerydateDatePickerLabel = uilabel(app.SubjectTab);
            app.SurgerydateDatePickerLabel.Text = 'Surgery date';
            app.SurgerydateDatePicker = uidatepicker(app.SubjectTab);
            app.AddSubjectButton = uibutton(app.SubjectTab, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @add_subject, true);
            app.AddSubjectButton.Text = 'Add';
            app.StrainDropDownLabel = uilabel(app.SubjectTab);
            app.StrainDropDownLabel.Text = 'Strain';
            app.StrainDropDown = uidropdown(app.SubjectTab);
            app.StrainDropDown.Items = {'C57BL/6J', 'BALB/cJ', 'FVB'};
            app.StrainDropDown.Value = 'C57BL/6J';
            app.SessionTab = uitab(app.TabGroup);
            app.SessionTab.Title = 'Session';
            app.SessionIDTextAreaLabel = uilabel(app.SessionTab);
            app.SessionIDTextAreaLabel.Text = 'Session ID';
            app.SessionIDTextArea = uitextarea(app.SessionTab);
            app.SessionIDTextArea.ValueChangedFcn = createCallbackFcn(app, @SessionIDTextAreaValueChanged, true);
            app.SessionIDTextArea.Tag = '__SESSION_ID';
            app.SessionIDTextArea.Editable = 'off';
            app.FOVSpinnerLabel = uilabel(app.SessionTab);
            app.FOVSpinnerLabel.Text = 'FOV';
            app.FOVSpinner = uispinner(app.SessionTab);
            app.FOVSpinner.Limits = [1 100];
            app.FOVSpinner.RoundFractionalValues = 'on';
            app.FOVSpinner.ValueChangedFcn = createCallbackFcn(app, @FOVSpinnerValueChanged, true);
            app.FOVSpinner.Tag = '__FOV_NUM';
            app.FOVSpinner.Value = 1;
            app.DescriptionSectionTextAreaLabel = uilabel(app.SessionTab);
            app.DescriptionSectionTextAreaLabel.Text = 'Description';
            app.SessionDescription = uitextarea(app.SessionTab);
            app.SessionDescription.ValueChangedFcn = createCallbackFcn(app, @SessionDescriptionValueChanged, true);
            app.SessionDescription.Tag = '__ANIMAL_ID';
            app.TimePointSpinnerLabel = uilabel(app.SessionTab);
            app.TimePointSpinnerLabel.Text = 'Time Point';
            app.TimePointSpinner = uispinner(app.SessionTab);
            app.TimePointSpinner.Limits = [0 100];
            app.TimePointSpinner.RoundFractionalValues = 'on';
            app.TimePointSpinner.ValueChangedFcn = createCallbackFcn(app, @TimePointSpinnerValueChanged, true);
            app.TimePointSpinner.Tag = '__FOV_NUM';
            app.ConditionDropDownLabel = uilabel(app.SessionTab);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.SessionTab);
            app.ConditionDropDown.Items = {'Baseline'};
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.ConditionDropDown.Value = 'Baseline';
            app.InitializeButton = uibutton(app.SessionTab, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Tag = '__ACTION_GRAB';
            app.InitializeButton.Enable = 'off';
            app.InitializeButton.Text = 'Initialize';
            app.Lamp = uilamp(app.SessionTab);
            app.SessionsubjectDropDownLabel = uilabel(app.SessionTab);
            app.SessionsubjectDropDownLabel.Text = 'Session subject';
            app.SessionsubjectDropDown = uidropdown(app.SessionTab);
            app.SessionsubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionsubjectDropDownValueChanged, true);
            app.WeightSpinnerLabel = uilabel(app.SessionTab);
            app.WeightSpinnerLabel.Text = 'Weight';
            app.WeightSpinner = uispinner(app.SessionTab);
            app.WeightSpinner.Step = 0.1;
            app.WeightSpinner.Limits = [0 Inf];
            app.AnesthesiaDropDownLabel = uilabel(app.SessionTab);
            app.AnesthesiaDropDownLabel.Text = 'Anesthesia';
            app.AnesthesiaDropDown = uidropdown(app.SessionTab);
            app.AnesthesiaDropDown.Items = {'None', 'Isofluorane '};
            app.AnesthesiaDropDown.Value = 'None';
            app.LabelingDropDownLabel = uilabel(app.SessionTab);
            app.LabelingDropDownLabel.Text = 'Labeling';
            app.LabelingDropDown = uidropdown(app.SessionTab);
            app.LabelingDropDown.Items = {'None', 'FITC 2MDa', 'TxRed 70KDa', ' '};
            app.LabelingDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelingDropDownValueChanged, true);
            app.LabelingDropDown.Value = 'None';
            app.PharmacolDropDownLabel = uilabel(app.SessionTab);
            app.PharmacolDropDownLabel.Text = 'Pharmacol';
            app.PharmacolDropDown = uidropdown(app.SessionTab);
            app.PharmacolDropDown.Items = {'None '};
            app.PharmacolDropDown.ValueChangedFcn = createCallbackFcn(app, @PharmacolDropDownValueChanged, true);
            app.PharmacolDropDown.Value = 'None ';
            app.CheckBox_FOV = uicheckbox(app.SessionTab);
            app.CheckBox_FOV.Tooltip = {'click to add to session name'};
            app.CheckBox_FOV.Text = '';
            app.CheckBox_Timepoint = uicheckbox(app.SessionTab);
            app.CheckBox_Timepoint.Tooltip = {'click to add to session name'};
            app.CheckBox_Timepoint.Text = '';
            app.CheckBox_Condition = uicheckbox(app.SessionTab);
            app.CheckBox_Condition.Tooltip = {'click to add to session name'};
            app.CheckBox_Condition.Text = '';
            app.CheckBox_Anesthesia = uicheckbox(app.SessionTab);
            app.CheckBox_Anesthesia.Tooltip = {'click to add to session name'};
            app.CheckBox_Anesthesia.Text = '';
            app.CheckBox_Labeling = uicheckbox(app.SessionTab);
            app.CheckBox_Labeling.Tooltip = {'click to add to session name'};
            app.CheckBox_Labeling.Text = '';
            app.CheckBox_Pharmacology = uicheckbox(app.SessionTab);
            app.CheckBox_Pharmacology.Tooltip = {'click to add to session name'};
            app.CheckBox_Pharmacology.Text = '';
            app.CheckBox_FOV_dir = uicheckbox(app.SessionTab);
            app.CheckBox_FOV_dir.Tooltip = {'click to add to session name'};
            app.CheckBox_FOV_dir.Text = '';
            app.CheckBox_Condition_dir = uicheckbox(app.SessionTab);
            app.CheckBox_Condition_dir.Tooltip = {'click to add to session name'};
            app.CheckBox_Condition_dir.Text = '';
            app.CheckBox_Anesthesia_dir = uicheckbox(app.SessionTab);
            app.CheckBox_Anesthesia_dir.Tooltip = {'click to add to session name'};
            app.CheckBox_Anesthesia_dir.Text = '';
            app.CheckBox_Labeling_dir = uicheckbox(app.SessionTab);
            app.CheckBox_Labeling_dir.Tooltip = {'click to add to session name'};
            app.CheckBox_Labeling_dir.Text = '';
            app.CheckBox_Pharmacology_dir = uicheckbox(app.SessionTab);
            app.CheckBox_Pharmacology_dir.Tooltip = {'click to add to session name'};
            app.CheckBox_Pharmacology_dir.Text = '';
            app.CheckBox_Timepoint_dir = uicheckbox(app.SessionTab);
            app.CheckBox_Timepoint_dir.Tooltip = {'click to add to session name'};
            app.CheckBox_Timepoint_dir.Text = '';
            app.PreviewButton = uibutton(app.SessionTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.StimDropDownLabel = uilabel(app.SessionTab);
            app.StimDropDownLabel.Text = 'Stim';
            app.StimDropDown = uidropdown(app.SessionTab);
            app.StimDropDown.Items = {'Spont', 'Spont & Stim - air puff', 'Spont $ Stim - flash', '', ''};
            app.StimDropDown.Value = 'Spont';
            app.CheckBox_Stim = uicheckbox(app.SessionTab);
            app.CheckBox_Stim.Tooltip = {'click to add to session name'};
            app.CheckBox_Stim.Text = '';
            app.CheckBox_Stim_dir = uicheckbox(app.SessionTab);
            app.CheckBox_Stim_dir.Tooltip = {'click to add to session name'};
            app.CheckBox_Stim_dir.Text = '';
            app.AcquireTab = uitab(app.TabGroup);
            app.AcquireTab.Title = 'Acquire';
            app.DatatypeDropDownLabel = uilabel(app.AcquireTab);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.AcquireTab);
            app.DatatypeDropDown.Items = {'Movie', 'Stack', 'Arbitrary Scan'};
            app.DatatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDownValueChanged, true);
            app.DatatypeDropDown.Value = 'Movie';
            app.DescriptionAcqTextAreaLabel = uilabel(app.AcquireTab);
            app.DescriptionAcqTextAreaLabel.Text = 'Description';
            app.DescriptionAcqTextArea = uitextarea(app.AcquireTab);
            app.DescriptionAcqTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionAcqTextAreaValueChanged, true);
            app.DescriptionAcqTextArea.Tag = '__ANIMAL_ID';
            app.AcquisitionIDTextAreaLabel = uilabel(app.AcquireTab);
            app.AcquisitionIDTextAreaLabel.Text = 'Acquisition ID';
            app.AcquisitionIDTextArea = uitextarea(app.AcquireTab);
            app.AcquisitionIDTextArea.Tag = '__SESSION_ID';
            app.AcquisitionIDTextArea.Editable = 'off';
            app.ObjectiveDropDownLabel = uilabel(app.AcquireTab);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.ObjectiveDropDown = uidropdown(app.AcquireTab);
            app.ObjectiveDropDown.Items = {'Zeiss 20x NA1.0', 'Olympus 20x NA 1.0', 'Olympuys 10x NA 0.6', 'Leica 25x NA 0.95'};
            app.ObjectiveDropDown.Value = 'Zeiss 20x NA1.0';
            app.DAQDropDownLabel = uilabel(app.AcquireTab);
            app.DAQDropDownLabel.Text = 'DAQ';
            app.DAQDropDown = uidropdown(app.AcquireTab);
            app.DAQDropDown.Items = {'NI', 'VDAQ'};
            app.DAQDropDown.Value = 'NI';
            app.ScannerDropDownLabel = uilabel(app.AcquireTab);
            app.ScannerDropDownLabel.Text = 'Scanner';
            app.ScannerDropDown = uidropdown(app.AcquireTab);
            app.ScannerDropDown.Items = {'RG', 'GG', 'RG & TAG', 'Mini2P-Fast', 'Mini2P-LFov', 'Miniscope', 'Widefield'};
            app.ScannerDropDown.Value = 'RG';
            app.PostacquisitionPanel = uipanel(app.AcquireTab);
            app.PostacquisitionPanel.Title = 'Post-acquisition ';
            app.CommentsTextAreaLabel = uilabel(app.PostacquisitionPanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.PostacquisitionPanel);
            app.CommentsTextArea.ValueChangedFcn = createCallbackFcn(app, @CommentsTextAreaValueChanged, true);
            app.UseforanalysisDropDownLabel = uilabel(app.PostacquisitionPanel);
            app.UseforanalysisDropDownLabel.Text = 'Use for analysis';
            app.UseforanalysisDropDown = uidropdown(app.PostacquisitionPanel);
            app.UseforanalysisDropDown.Items = {'TBD', 'YES', 'NO'};
            app.UseforanalysisDropDown.Value = 'TBD';
            app.AcquisitionIDDropDownLabel = uilabel(app.PostacquisitionPanel);
            app.AcquisitionIDDropDownLabel.Text = 'Acquisition ID';
            app.AcquisitionIDDropDown = uidropdown(app.PostacquisitionPanel);
            app.AcquisitionIDDropDown.Items = {'00000'};
            app.AcquisitionIDDropDown.Value = '00000';
            app.UpdateButton_postacq = uibutton(app.PostacquisitionPanel, 'push');
            app.UpdateButton_postacq.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_postacqPushed, true);
            app.UpdateButton_postacq.Text = 'Update';
            app.GrabButton = uibutton(app.AcquireTab, 'push');
            app.GrabButton.ButtonPushedFcn = createCallbackFcn(app, @GrabButtonPushed, true);
            app.GrabButton.Tag = '__ACTION_GRAB';
            app.GrabButton.Text = 'Grab';
            app.AcquireanalogdataCheckBox = uicheckbox(app.AcquireTab);
            app.AcquireanalogdataCheckBox.Tag = '__ACTION_AUTO_SESSION_ID';
            app.AcquireanalogdataCheckBox.Tooltip = {'Create from name, date and time.'};
            app.AcquireanalogdataCheckBox.Text = 'Acquire analog data';
            app.AcquireanalogdataCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SI_data_logger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end